classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AboutButton      matlab.ui.control.Button
        GraphButton      matlab.ui.control.Button
        PECheckBox       matlab.ui.control.CheckBox
        KECheckBox       matlab.ui.control.CheckBox
        InitialsPanel    matlab.ui.container.Panel
        xEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel  matlab.ui.control.Label
        tEditField       matlab.ui.control.NumericEditField
        tEditFieldLabel  matlab.ui.control.Label
        vEditField       matlab.ui.control.NumericEditField
        vEditFieldLabel  matlab.ui.control.Label
        ParametersPanel  matlab.ui.container.Panel
        mEditField       matlab.ui.control.NumericEditField
        mEditFieldLabel  matlab.ui.control.Label
        qEditField       matlab.ui.control.NumericEditField
        qLabel           matlab.ui.control.Label
        kEditField       matlab.ui.control.NumericEditField
        kLabel           matlab.ui.control.Label
        Label            matlab.ui.control.Label
        Image            matlab.ui.control.Image
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Private properties
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            ComputeEnergy(app);
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            if app.mEditField.Value <= 0
                app.mEditField.Value = 1;
                errordlg('Input must be greater than zero!', 'Invalid Input', 'modal');
            end
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            if app.tEditField.Value <= 0
                app.tEditField.Value = 10;
                errordlg('Input must be greater than zero!', 'Invalid Input', 'modal');
            end
        end

        % Value changed function: KECheckBox
        function KECheckBoxValueChanged(app, event)
            if ~app.KECheckBox.Value && ~app.PECheckBox.Value
                app.KECheckBox.Value = true;
                errordlg('At least one of the two checkboxes should be activated!', 'Warning', 'modal');
            elseif length(app.UIAxes.Children) == 2
                if app.KECheckBox.Value
                    set(app.UIAxes.Children(2), 'Visible', 'on');
                else
                    set(app.UIAxes.Children(2), 'Visible', 'off');
                end
            end
        end

        % Value changed function: PECheckBox
        function PECheckBoxValueChanged(app, event)
            if ~app.PECheckBox.Value && ~app.KECheckBox.Value
                app.PECheckBox.Value = true;
                errordlg('At least one of the two checkboxes should be activated!', 'Warning', 'modal');
            elseif length(app.UIAxes.Children) == 2
                if app.PECheckBox.Value
                    set(app.UIAxes.Children(1), 'Visible', 'on');
                else
                    set(app.UIAxes.Children(1), 'Visible', 'off');
                end
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            web('https://github.com/datdadev/MATLAB-Conservative-Force-Energy-Analysis', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Energy (J)')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logoBK.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Kinetic and Potential Energy under'; 'influence of Conservative Force'};
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.kLabel = uilabel(app.ParametersPanel);
            app.kLabel.Text = 'k =';
            app.kEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.kEditField.Value = 1;
            app.qLabel = uilabel(app.ParametersPanel);
            app.qLabel.Text = 'q =';
            app.qEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.qEditField.Value = 0.1;
            app.mEditFieldLabel = uilabel(app.ParametersPanel);
            app.mEditFieldLabel.Text = 'm =';
            app.mEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.mEditField.Limits = [0 Inf];
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 1;
            app.InitialsPanel = uipanel(app.UIFigure);
            app.InitialsPanel.Title = 'Initials';
            app.vEditFieldLabel = uilabel(app.InitialsPanel);
            app.vEditFieldLabel.Text = 'v =';
            app.vEditField = uieditfield(app.InitialsPanel, 'numeric');
            app.tEditFieldLabel = uilabel(app.InitialsPanel);
            app.tEditFieldLabel.Text = 't =';
            app.tEditField = uieditfield(app.InitialsPanel, 'numeric');
            app.tEditField.Limits = [0 Inf];
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.tEditField.Value = 10;
            app.xEditFieldLabel = uilabel(app.InitialsPanel);
            app.xEditFieldLabel.Text = 'x =';
            app.xEditField = uieditfield(app.InitialsPanel, 'numeric');
            app.xEditField.Value = 0.1;
            app.KECheckBox = uicheckbox(app.UIFigure);
            app.KECheckBox.ValueChangedFcn = createCallbackFcn(app, @KECheckBoxValueChanged, true);
            app.KECheckBox.Text = 'K.E.';
            app.KECheckBox.Value = true;
            app.PECheckBox = uicheckbox(app.UIFigure);
            app.PECheckBox.ValueChangedFcn = createCallbackFcn(app, @PECheckBoxValueChanged, true);
            app.PECheckBox.Text = 'P.E.';
            app.PECheckBox.Value = true;
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end